classdef NuclearCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NuclearCounterUIFigure          matlab.ui.Figure
        Menu_File                       matlab.ui.container.Menu
        Menu_LoadFile                   matlab.ui.container.Menu
        Menu_SaveResults                matlab.ui.container.Menu
        Menu_Setting                    matlab.ui.container.Menu
        Menu_OptimizerSwitch            matlab.ui.container.Menu
        Menu_OptimizerEnable            matlab.ui.container.Menu
        Menu_OptimizerDisable           matlab.ui.container.Menu
        Menu_LoadConfiguration          matlab.ui.container.Menu
        Menu_SaveConfiguration          matlab.ui.container.Menu
        Menu_Help                       matlab.ui.container.Menu
        Menu_About                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Panel_ExportSetting             matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        DropDown_ExportMarker           matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        EditField_ExportFrameRatio      matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        DropDown_ExportResolution       matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_ExportFileFormat       matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        Panel_Counter                   matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup_Counter                matlab.ui.container.TabGroup
        Tab_ParameterSetting            matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_Gamma                 matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        EditField_ObjectRejectThreshold  matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_ObjectMergeThreshold  matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        EditField_ObjectSensitivity     matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_GlobalRehistThreshold  matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Tab_OptimizerSetting            matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        Spinner_PotentialWellRelaxation  matlab.ui.control.Spinner
        Label_18                        matlab.ui.control.Label
        EditField_TradeoffCoefficient   matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        EditField_ObjectNumberRange     matlab.ui.control.EditField
        Label_13                        matlab.ui.control.Label
        EditField_Tolerance             matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        EditField_IterationNumberMax    matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        Panel_DataSource                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EditField_CameraBackground      matlab.ui.control.NumericEditField
        Label_10                        matlab.ui.control.Label
        DropDown_Stage                  matlab.ui.control.DropDown
        StageDropDownLabel              matlab.ui.control.Label
        DropDown_Driver                 matlab.ui.control.DropDown
        DriverDropDownLabel             matlab.ui.control.Label
        DropDown_Marker                 matlab.ui.control.DropDown
        MarkerDropDownLabel             matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        Button_Run                      matlab.ui.control.StateButton
        GridLayout_ProcessBar           matlab.ui.container.GridLayout
        Label_ProcessBarFill            matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_SystemLogOutput           matlab.ui.control.Label
        GridLayout10                    matlab.ui.container.GridLayout
        Slider_Slice                    matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        UIAxes_Viewer                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        animal      % 1-by-1 struct, the animal arguments
        imorph      % 1-by-1 struct, the input arguments of morphology
        optimal     % 1-by-1 struct, the optimal arguments
        output      % 1-by-1 struct, the output arguments
        background  % 1-by-1 nonnegtive integer or string, scalar
    end
    
    methods (Access = private)
        
        function InitData(app)
            app.animal = struct('marker', "mCherry", ...
                'driver', "OK107", ...
                'stage', "L1");

            app.imorph = struct('rehist_quantile', 0.975, ...
                'cir_ssy',0.935, ...
                'simr_th',0.7, ...
                'avgI_th',0.015, ...
                'gamma', 0.5);

            app.optimal = struct('maxitn', 200, ...  % for maximum iteration number
                'tol', 1e-4, ...       % the tolerance
                'state', 'off', ...    % optimal running flag, 'on' or 'off'
                'srange', [80,120], ... % the valid search range
                'rlx', 2, ...          % the relaxation of symmetric potential well, positive even number
                'trdof', 0.5);         % the objects tradeoff, 0 ~ 1,
                                       % 0 for more valid objects is important,
                                       % 1 for less uncertain objects is important
            app.output = struct('is_display', true,...
                'is_saving', false, ...
                'validobj_only', false,...
                'format',   'png', ...
                'resolution',300, ...
                'fr', 10);

            app.background = Inf;
        end

        function InitPanel(app)
            InitDataSourcePanel(app);
            InitCounterPanel(app);
            InitExportSettingPanel(app);

            app.Slider_Slice.Enable = "off";
            app.Button_Run.Enable = "off";
        end

        function InitMenu(app)
            app.Menu_SaveResults.Enable = "off";
            app.Menu_OptimizerDisable.Checked = "on";
        end

        function InitDataSourcePanel(app)
            app.DropDown_Marker.Value = app.animal.marker;
            app.DropDown_Driver.Value = app.animal.driver;
            app.DropDown_Stage.Value = app.animal.stage;
            app.EditField_CameraBackground.Value = app.background;
        end

        function InitCounterPanel(app)
            % counter parameters tab
            app.EditField_GlobalRehistThreshold.Value = app.imorph.rehist_quantile;
            app.EditField_ObjectSensitivity.Value = app.imorph.cir_ssy;
            app.EditField_ObjectMergeThreshold.Value = app.imorph.simr_th;
            app.EditField_ObjectRejectThreshold.Value = app.imorph.avgI_th;
            app.EditField_Gamma.Value = app.imorph.gamma;

            % optimal parameters tab
            app.EditField_IterationNumberMax.Value = app.optimal.maxitn;
            app.EditField_Tolerance.Value = app.optimal.tol;
            app.EditField_ObjectNumberRange.Value = ...
                string(app.optimal.srange).join(",");
            app.EditField_TradeoffCoefficient.Value = app.optimal.trdof;
            app.Spinner_PotentialWellRelaxation.Value = app.optimal.rlx/2;

            LockOptimizerSetting(app);
        end

        function InitExportSettingPanel(app)
            [~, pidx] = ismember(app.output.format, ["png","tif","avi"]);
            app.DropDown_ExportFileFormat.Value = ...
                app.DropDown_ExportFileFormat.Items(pidx);
            if app.output.format ~= "avi"
                app.EditField_ExportFrameRatio.Enable = "off";
            else
                app.EditField_ExportFrameRatio.Enable = "on";
            end
            
            [~, pidx] = ismember(app.output.resolution, [150,300,600]);
            app.DropDown_ExportResolution.Value = ...
                app.DropDown_ExportResolution.Items(pidx);

            app.EditField_ExportFrameRatio.Value = app.output.fr;

            if app.output.validobj_only == true
                app.DropDown_ExportMarker.Value = ...
                    app.DropDown_ExportMarker.Items(1);
            else
                app.DropDown_ExportMarker.Value = ...
                    app.DropDown_ExportMarker.Items(2);
            end
        end

        function LockOptimizerSetting(app)
            app.EditField_IterationNumberMax.Enable = "off";
            app.EditField_Tolerance.Enable = "off";
            app.EditField_ObjectNumberRange.Enable = "off";
            app.EditField_TradeoffCoefficient.Enable = "off";
            app.Spinner_PotentialWellRelaxation.Enable = "off";
        end

        function UnlockOptimizerSetting(app)
            app.EditField_IterationNumberMax.Enable = "on";
            app.EditField_Tolerance.Enable = "on";
            app.EditField_ObjectNumberRange.Enable = "on";
            app.EditField_TradeoffCoefficient.Enable = "on";
            app.Spinner_PotentialWellRelaxation.Enable = "on";
        end

        function SetProgressBar(app, x)
            % This funciton set the progress bar locked on panel
            % x for percentage of progress, 0-1 real number
            arguments
                app
                x (1,1) double {mustBeInRange(x, 0, 1)} = 0
            end

            app.GridLayout_ProcessBar.ColumnWidth = round(x*200);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitData(app);
            InitMenu(app);
            InitPanel(app);

        end

        % Menu selected function: Menu_LoadFile
        function Menu_LoadFileSelected(app, event)
            
        end

        % Menu selected function: Menu_SaveResults
        function Menu_SaveResultsSelected(app, event)
            
        end

        % Menu selected function: Menu_OptimizerEnable
        function Menu_OptimizerEnableSelected(app, event)
            app.Menu_OptimizerEnable.Checked = "on";
            app.Menu_OptimizerDisable.Checked = "off";

            UnlockOptimizerSetting(app);
            app.optimal.state = 'on';
        end

        % Menu selected function: Menu_OptimizerDisable
        function Menu_OptimizerDisableSelected(app, event)
            app.Menu_OptimizerEnable.Checked = "off";
            app.Menu_OptimizerDisable.Checked = "on";

            LockOptimizerSetting(app);
            app.optimal.state = 'off';
        end

        % Menu selected function: Menu_LoadConfiguration
        function Menu_LoadConfigurationSelected(app, event)
            
        end

        % Menu selected function: Menu_SaveConfiguration
        function Menu_SaveConfigurationSelected(app, event)
            
        end

        % Menu selected function: Menu_About
        function Menu_AboutSelected(app, event)
            msg = sprintf(['Automatic 3D Nuclears Counter \n' ...
                'Version: 1.0.0(Panda) preview @si-lab \n' ...
                'Author: Weihan Li \n' ...
                'E-mail: liweihan20@mails.ucas.ac.cn']);
            uialert(app.NuclearCounterUIFigure, msg, "消息", "Icon","info");
        end

        % Value changed function: DropDown_Marker
        function DropDown_MarkerValueChanged(app, event)
            value = app.DropDown_Marker.Value;
            
            app.animal.marker = string(value);
        end

        % Value changed function: DropDown_Driver
        function DropDown_DriverValueChanged(app, event)
            value = app.DropDown_Driver.Value;
            
            app.animal.driver = string(value);
        end

        % Value changed function: DropDown_Stage
        function DropDown_StageValueChanged(app, event)
            value = app.DropDown_Stage.Value;
            
            app.animal.stage = string(value);
        end

        % Value changed function: EditField_CameraBackground
        function EditField_CameraBackgroundValueChanged(app, event)
            value = app.EditField_CameraBackground.Value;

            if isinf(value)
                app.background = "auto";
            else
                app.background = value;
            end
        end

        % Value changed function: EditField_GlobalRehistThreshold
        function EditField_GlobalRehistThresholdValueChanged(app, event)
            value = app.EditField_GlobalRehistThreshold.Value;
            
            app.imorph.rehist_quantile = value;
        end

        % Value changed function: EditField_ObjectSensitivity
        function EditField_ObjectSensitivityValueChanged(app, event)
            value = app.EditField_ObjectSensitivity.Value;
            
            app.imorph.cir_ssy = value;
        end

        % Value changed function: EditField_ObjectMergeThreshold
        function EditField_ObjectMergeThresholdValueChanged(app, event)
            value = app.EditField_ObjectMergeThreshold.Value;
            
            app.imorph.simr_th = value;
        end

        % Value changed function: EditField_ObjectRejectThreshold
        function EditField_ObjectRejectThresholdValueChanged(app, event)
            value = app.EditField_ObjectRejectThreshold.Value;
            
            app.imorph.avgI_th = value;
        end

        % Value changed function: EditField_Gamma
        function EditField_GammaValueChanged(app, event)
            value = app.EditField_Gamma.Value;
            
            app.imorph.gamma = value;
        end

        % Value changed function: EditField_IterationNumberMax
        function EditField_IterationNumberMaxValueChanged(app, event)
            value = app.EditField_IterationNumberMax.Value;
            
            app.optimal.maxitn = value;
        end

        % Value changed function: EditField_Tolerance
        function EditField_ToleranceValueChanged(app, event)
            value = app.EditField_Tolerance.Value;
            
            app.optimal.tol = value;
        end

        % Value changed function: EditField_ObjectNumberRange
        function EditField_ObjectNumberRangeValueChanged(app, event)
            value = app.EditField_ObjectNumberRange.Value;
            
            app.optimal.srange = str2num(value); %#ok<ST2NM>
        end

        % Value changed function: EditField_TradeoffCoefficient
        function EditField_TradeoffCoefficientValueChanged(app, event)
            value = app.EditField_TradeoffCoefficient.Value;
            
            app.optimal.trdof = value;
        end

        % Value changed function: Spinner_PotentialWellRelaxation
        function Spinner_PotentialWellRelaxationValueChanged(app, event)
            value = app.Spinner_PotentialWellRelaxation.Value;
            
            app.optimal.rlx = value*2;
        end

        % Value changed function: DropDown_ExportFileFormat
        function DropDown_ExportFileFormatValueChanged(app, event)
            value = app.DropDown_ExportFileFormat.Value;
            
            switch value
                case "PNG series"
                    app.output.format = "png";
                    app.EditField_ExportFrameRatio.Enable = "off";
                case "TIFF stack"
                    app.output.format = "tif";
                    app.EditField_ExportFrameRatio.Enable = "off";
                case "AVI"
                    app.output.format = "avi";
                    app.EditField_ExportFrameRatio.Enable = "on";
                otherwise
                    % unsupported format
            end
        end

        % Value changed function: DropDown_ExportResolution
        function DropDown_ExportResolutionValueChanged(app, event)
            value = app.DropDown_ExportResolution.Value;
            
            app.output.resolution = str2double(...
                string(value).extractBefore(" ppi"));
        end

        % Value changed function: EditField_ExportFrameRatio
        function EditField_ExportFrameRatioValueChanged(app, event)
            value = app.EditField_ExportFrameRatio.Value;
            
            app.output.fr = value;
        end

        % Value changed function: DropDown_ExportMarker
        function DropDown_ExportMarkerValueChanged(app, event)
            value = app.DropDown_ExportMarker.Value;
            
            switch value
                case "Valid"
                    app.output.validobj_only = true;
                case "Total"
                    app.output.validobj_only = false;
                otherwise
            end
        end

        % Value changed function: Slider_Slice
        function Slider_SliceValueChanged(app, event)
            value = app.Slider_Slice.Value;
            
        end

        % Value changed function: Button_Run
        function Button_RunValueChanged(app, event)
            value = app.Button_Run.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NuclearCounterUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {650, 650};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NuclearCounterUIFigure = uifigure('Visible', 'off');
            app.NuclearCounterUIFigure.Name = 'Nuclear Counter';
            app.NuclearCounterUIFigure.Icon = fullfile(pathToMLAPP, 'sources', 'counter.png');
            app.NuclearCounterUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu_File = uimenu(app.NuclearCounterUIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_LoadFile = uimenu(app.Menu_File);
            app.Menu_LoadFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadFileSelected, true);
            app.Menu_LoadFile.Text = '导入文件';
            app.Menu_SaveResults = uimenu(app.Menu_File);
            app.Menu_SaveResults.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveResultsSelected, true);
            app.Menu_SaveResults.Text = '保存结果';
            app.Menu_Setting = uimenu(app.NuclearCounterUIFigure);
            app.Menu_Setting.Text = '设置';
            app.Menu_OptimizerSwitch = uimenu(app.Menu_Setting);
            app.Menu_OptimizerSwitch.Text = '优化器';
            app.Menu_OptimizerEnable = uimenu(app.Menu_OptimizerSwitch);
            app.Menu_OptimizerEnable.MenuSelectedFcn = createCallbackFcn(app, @Menu_OptimizerEnableSelected, true);
            app.Menu_OptimizerEnable.Text = '启用';
            app.Menu_OptimizerDisable = uimenu(app.Menu_OptimizerSwitch);
            app.Menu_OptimizerDisable.MenuSelectedFcn = createCallbackFcn(app, @Menu_OptimizerDisableSelected, true);
            app.Menu_OptimizerDisable.Text = '禁用';
            app.Menu_LoadConfiguration = uimenu(app.Menu_Setting);
            app.Menu_LoadConfiguration.MenuSelectedFcn = createCallbackFcn(app, @Menu_LoadConfigurationSelected, true);
            app.Menu_LoadConfiguration.Text = '导入设置';
            app.Menu_SaveConfiguration = uimenu(app.Menu_Setting);
            app.Menu_SaveConfiguration.MenuSelectedFcn = createCallbackFcn(app, @Menu_SaveConfigurationSelected, true);
            app.Menu_SaveConfiguration.Text = '导出设置';
            app.Menu_Help = uimenu(app.NuclearCounterUIFigure);
            app.Menu_Help.Text = '帮助';
            app.Menu_About = uimenu(app.Menu_Help);
            app.Menu_About.MenuSelectedFcn = createCallbackFcn(app, @Menu_AboutSelected, true);
            app.Menu_About.Text = '关于';
            app.GridLayout = uigridlayout(app.NuclearCounterUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Panel_DataSource = uipanel(app.GridLayout2);
            app.Panel_DataSource.Title = '数据源';
            app.GridLayout3 = uigridlayout(app.Panel_DataSource);
            app.MarkerDropDownLabel = uilabel(app.GridLayout3);
            app.MarkerDropDownLabel.Text = 'Marker';
            app.DropDown_Marker = uidropdown(app.GridLayout3);
            app.DropDown_Marker.Items = {'mCherry', 'RFP', 'GFP'};
            app.DropDown_Marker.ValueChangedFcn = createCallbackFcn(app, @DropDown_MarkerValueChanged, true);
            app.DropDown_Marker.Value = 'mCherry';
            app.DriverDropDownLabel = uilabel(app.GridLayout3);
            app.DriverDropDownLabel.Text = 'Driver';
            app.DropDown_Driver = uidropdown(app.GridLayout3);
            app.DropDown_Driver.Items = {'OK107', 'Orco'};
            app.DropDown_Driver.ValueChangedFcn = createCallbackFcn(app, @DropDown_DriverValueChanged, true);
            app.DropDown_Driver.Value = 'OK107';
            app.StageDropDownLabel = uilabel(app.GridLayout3);
            app.StageDropDownLabel.Text = 'Stage';
            app.DropDown_Stage = uidropdown(app.GridLayout3);
            app.DropDown_Stage.Items = {'Egg', 'L1', 'L2', 'L3', 'Pupa', 'Adult'};
            app.DropDown_Stage.ValueChangedFcn = createCallbackFcn(app, @DropDown_StageValueChanged, true);
            app.DropDown_Stage.Value = 'L1';
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '相机背景';
            app.EditField_CameraBackground = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_CameraBackground.Limits = [0 Inf];
            app.EditField_CameraBackground.ValueChangedFcn = createCallbackFcn(app, @EditField_CameraBackgroundValueChanged, true);
            app.EditField_CameraBackground.Tooltip = {'Inf for background '; ' detected automatically'};
            app.EditField_CameraBackground.Value = Inf;
            app.Panel_Counter = uipanel(app.GridLayout2);
            app.Panel_Counter.Title = '计数器';
            app.GridLayout4 = uigridlayout(app.Panel_Counter);
            app.TabGroup_Counter = uitabgroup(app.GridLayout4);
            app.Tab_ParameterSetting = uitab(app.TabGroup_Counter);
            app.Tab_ParameterSetting.Title = '参数设置';
            app.GridLayout7 = uigridlayout(app.Tab_ParameterSetting);
            app.Label_5 = uilabel(app.GridLayout7);
            app.Label_5.Text = '全局下阈值';
            app.EditField_GlobalRehistThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_GlobalRehistThreshold.Limits = [0 1];
            app.EditField_GlobalRehistThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_GlobalRehistThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_GlobalRehistThresholdValueChanged, true);
            app.EditField_GlobalRehistThreshold.Value = 0.975;
            app.Label_6 = uilabel(app.GridLayout7);
            app.Label_6.Text = '灵敏度';
            app.EditField_ObjectSensitivity = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectSensitivity.Limits = [0 1];
            app.EditField_ObjectSensitivity.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectSensitivity.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectSensitivityValueChanged, true);
            app.EditField_ObjectSensitivity.Value = 0.935;
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '目标合并阈';
            app.EditField_ObjectMergeThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectMergeThreshold.Limits = [0 1];
            app.EditField_ObjectMergeThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectMergeThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectMergeThresholdValueChanged, true);
            app.EditField_ObjectMergeThreshold.Value = 0.7;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '目标拒绝阈';
            app.EditField_ObjectRejectThreshold = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_ObjectRejectThreshold.Limits = [0 1];
            app.EditField_ObjectRejectThreshold.ValueDisplayFormat = '%.3f';
            app.EditField_ObjectRejectThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectRejectThresholdValueChanged, true);
            app.EditField_ObjectRejectThreshold.Value = 0.015;
            app.Label_9 = uilabel(app.GridLayout7);
            app.Label_9.Text = '伽马';
            app.EditField_Gamma = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_Gamma.Limits = [0 1];
            app.EditField_Gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_GammaValueChanged, true);
            app.EditField_Gamma.Value = 0.5;
            app.Tab_OptimizerSetting = uitab(app.TabGroup_Counter);
            app.Tab_OptimizerSetting.Title = '优化器';
            app.GridLayout8 = uigridlayout(app.Tab_OptimizerSetting);
            app.Label_11 = uilabel(app.GridLayout8);
            app.Label_11.Text = '迭代数上限';
            app.EditField_IterationNumberMax = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_IterationNumberMax.Limits = [0 Inf];
            app.EditField_IterationNumberMax.ValueDisplayFormat = '%.0f';
            app.EditField_IterationNumberMax.ValueChangedFcn = createCallbackFcn(app, @EditField_IterationNumberMaxValueChanged, true);
            app.EditField_IterationNumberMax.Value = 200;
            app.Label_12 = uilabel(app.GridLayout8);
            app.Label_12.Text = '公差';
            app.EditField_Tolerance = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_Tolerance.Limits = [0 1];
            app.EditField_Tolerance.ValueDisplayFormat = '%1.4g';
            app.EditField_Tolerance.ValueChangedFcn = createCallbackFcn(app, @EditField_ToleranceValueChanged, true);
            app.EditField_Tolerance.Value = 0.0001;
            app.Label_13 = uilabel(app.GridLayout8);
            app.Label_13.Text = '计数范围';
            app.EditField_ObjectNumberRange = uieditfield(app.GridLayout8, 'text');
            app.EditField_ObjectNumberRange.CharacterLimits = [3 Inf];
            app.EditField_ObjectNumberRange.ValueChangedFcn = createCallbackFcn(app, @EditField_ObjectNumberRangeValueChanged, true);
            app.EditField_ObjectNumberRange.Placeholder = '80,120';
            app.Label_14 = uilabel(app.GridLayout8);
            app.Label_14.Text = '平衡因子';
            app.EditField_TradeoffCoefficient = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_TradeoffCoefficient.Limits = [0 1];
            app.EditField_TradeoffCoefficient.ValueDisplayFormat = '%.3f';
            app.EditField_TradeoffCoefficient.ValueChangedFcn = createCallbackFcn(app, @EditField_TradeoffCoefficientValueChanged, true);
            app.EditField_TradeoffCoefficient.Value = 0.5;
            app.Label_18 = uilabel(app.GridLayout8);
            app.Label_18.Text = '边界松弛';
            app.Spinner_PotentialWellRelaxation = uispinner(app.GridLayout8);
            app.Spinner_PotentialWellRelaxation.Limits = [0 Inf];
            app.Spinner_PotentialWellRelaxation.ValueDisplayFormat = '%.0f';
            app.Spinner_PotentialWellRelaxation.ValueChangedFcn = createCallbackFcn(app, @Spinner_PotentialWellRelaxationValueChanged, true);
            app.Spinner_PotentialWellRelaxation.Value = 1;
            app.Panel_ExportSetting = uipanel(app.GridLayout2);
            app.Panel_ExportSetting.Title = '导出设置';
            app.GridLayout5 = uigridlayout(app.Panel_ExportSetting);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '导出格式';
            app.DropDown_ExportFileFormat = uidropdown(app.GridLayout5);
            app.DropDown_ExportFileFormat.Items = {'PNG series', 'TIFF stack', 'AVI'};
            app.DropDown_ExportFileFormat.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportFileFormatValueChanged, true);
            app.DropDown_ExportFileFormat.Value = 'PNG series';
            app.Label_2 = uilabel(app.GridLayout5);
            app.Label_2.Text = '分辨率';
            app.DropDown_ExportResolution = uidropdown(app.GridLayout5);
            app.DropDown_ExportResolution.Items = {'150 ppi', '300 ppi', '600 ppi'};
            app.DropDown_ExportResolution.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportResolutionValueChanged, true);
            app.DropDown_ExportResolution.Value = '300 ppi';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.FPSEditFieldLabel = uilabel(app.GridLayout6);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.EditField_ExportFrameRatio = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_ExportFrameRatio.Limits = [0.01 100];
            app.EditField_ExportFrameRatio.ValueDisplayFormat = '%.2f';
            app.EditField_ExportFrameRatio.ValueChangedFcn = createCallbackFcn(app, @EditField_ExportFrameRatioValueChanged, true);
            app.EditField_ExportFrameRatio.Value = 10;
            app.Label_3 = uilabel(app.GridLayout5);
            app.Label_3.Text = '帧率';
            app.Label_4 = uilabel(app.GridLayout5);
            app.Label_4.Text = '导出标记';
            app.DropDown_ExportMarker = uidropdown(app.GridLayout5);
            app.DropDown_ExportMarker.Items = {'Valid', 'Total'};
            app.DropDown_ExportMarker.ValueChangedFcn = createCallbackFcn(app, @DropDown_ExportMarkerValueChanged, true);
            app.DropDown_ExportMarker.Value = 'Valid';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout9 = uigridlayout(app.RightPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.UIAxes_Viewer = uiaxes(app.GridLayout10);
            app.UIAxes_Viewer.XTick = [];
            app.UIAxes_Viewer.YTick = [];
            app.UIAxes_Viewer.Visible = 'off';
            app.SliderLabel = uilabel(app.GridLayout10);
            app.SliderLabel.Text = '片层';
            app.Slider_Slice = uislider(app.GridLayout10);
            app.Slider_Slice.MajorTicks = [];
            app.Slider_Slice.ValueChangedFcn = createCallbackFcn(app, @Slider_SliceValueChanged, true);
            app.Slider_Slice.MinorTicks = [];
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.Label_SystemLogOutput = uilabel(app.GridLayout11);
            app.Label_SystemLogOutput.Text = '';
            app.Label_17 = uilabel(app.GridLayout11);
            app.Label_17.Text = '[系统]';
            app.GridLayout_ProcessBar = uigridlayout(app.GridLayout11);
            app.Label_ProcessBarFill = uilabel(app.GridLayout_ProcessBar);
            app.Label_ProcessBarFill.Text = '';
            app.Button_Run = uibutton(app.GridLayout11, 'state');
            app.Button_Run.ValueChangedFcn = createCallbackFcn(app, @Button_RunValueChanged, true);
            app.Button_Run.Icon = fullfile(pathToMLAPP, 'sources', 'run.png');
            app.Button_Run.Text = '启动';
            % Show the figure after all components are created
            app.NuclearCounterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NuclearCounter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NuclearCounterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NuclearCounterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NuclearCounterUIFigure)
        end
    end
end